classdef qualitycheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SelectFolderButton    matlab.ui.control.Button
        FolderEditField       matlab.ui.control.EditField
        ListBox               matlab.ui.control.ListBox
        UIAxes                matlab.ui.control.UIAxes
        CheckButton           matlab.ui.control.Button
        MarkerDropDown        matlab.ui.control.DropDown
        ConsistencyLampLabel  matlab.ui.control.Label
        ConsistencyLamp       matlab.ui.control.Lamp
    end


    properties (Access = private)
        mnames = {}; % Description
        data = {};
    end


    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            
            folder_name = uigetdir('D:\OneDrive\MyProject\RGD Hydrogel\Compensated FCS files for HD analysis - days 3, 7, 14');
            app.FolderEditField.Value = folder_name;
            files = dir([app.FolderEditField.Value '\*.fcs']);
            fname = {files.name};
            app.ListBox.Items = fname;            
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)

            app.mnames = {};
            folder = [app.FolderEditField.Value '\'];
            app.ConsistencyLamp.Color = 'y';
            for i = 1:length(app.ListBox.Items)
                fname = app.ListBox.Items{i};
                [~, mname,~,~,d] = readfcs_v2([folder fname]);
                d = flow_arcsinh(d, 100)';
                app.mnames{i} = mname;
                app.data{i} = d;
                if i > 1 && ~isempty(setdiff(app.mnames{1}, mname))
                    app.ConsistencyLamp.Color = 'r';
                end
            end
            if app.ConsistencyLamp.Color ~= 'y'
                app.ConsistencyLamp.Color = 'g';
            end
            app.MarkerDropDown.Items = app.mnames{1};
        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            if isempty(app.MarkerDropDown.Value)
                return
            end
            for j = 1:length(app.MarkerDropDown.Items)
                if strcmp(app.MarkerDropDown.Items{j}, app.MarkerDropDown.Value)
                    idx = j;
                end
            end
            ax = app.UIAxes;
            cla(ax);
            hold(ax, 'on');
            for i = 1:length(app.data)
                d = app.data{i};
                [f,xi] = ksdensity(d(:,idx));
                plot(ax, xi, f);
            end
            title(ax, app.MarkerDropDown.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Value = '.';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.MarkerDropDown = uidropdown(app.UIFigure);
            app.MarkerDropDown.Items = {};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Value = {};
            app.ConsistencyLampLabel = uilabel(app.UIFigure);
            app.ConsistencyLampLabel.Text = 'Consistency';
            app.ConsistencyLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = qualitycheck

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end